classdef Derivadas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DerivadasLabel                 matlab.ui.control.Label
        ErrorEditField                 matlab.ui.control.EditField
        ErrorEditFieldLabel            matlab.ui.control.Label
        ValorRealEditField             matlab.ui.control.EditField
        ValorRealEditFieldLabel        matlab.ui.control.Label
        ValorAproximadoEditField       matlab.ui.control.EditField
        ValorAproximadoEditFieldLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        FuncionEditField               matlab.ui.control.EditField
        FuncionEditFieldLabel          matlab.ui.control.Label
        ValorX                         matlab.ui.control.NumericEditField
        ValoraevaliarLabel_3           matlab.ui.control.Label
        ValorSalto                     matlab.ui.control.NumericEditField
        ValoraevaliarLabel_2           matlab.ui.control.Label
        VolverButton                   matlab.ui.control.Button
        CalcularButton                 matlab.ui.control.Button
        DerivadaDropDown               matlab.ui.control.DropDown
        DerivadaDropDownLabel          matlab.ui.control.Label
    end


    methods (Access = public)

        function m = metodos(app, derivada, x, funcion, h)
            % Crear un vector de valores de x en el rango deseado
            x_values = linspace(x+3*h, x-3*h, 7); % El número 7 es el número de valores en el vector

            fx = str2func(['@(x)' funcion]);

            % Evaluar la función en cada valor de x usando arrayfun
            fx_values = arrayfun(fx, x_values);

            disp (fx_values)

            switch derivada
                case "Primera derivada"
                    resultado = (-fx_values(2) + 8*fx_values(3) - 8*fx_values(5) + fx_values(6)) / (12*h);
                case "Segunda derivada"
                    resultado = (-fx_values(2) + 16*fx_values(3) - 30*fx_values(4) + 16*fx_values(5) - fx_values(6)) / (12*(h^2));
                case "Tercera derivada"
                    resultado = (-fx_values(1) + 8*fx_values(2) - 13*fx_values(3) + 13*fx_values(5) - 8*fx_values(6) + fx_values(7)) / (8*(h^3));
                case "Cuarta derivada"
                    resultado = (-fx_values(1) + 12*fx_values(2) + 39*fx_values(3) + 56*fx_values(4) - 39*fx_values(5) + 12*fx_values(6) + fx_values(7)) / (6*(h^4));
                otherwise
                    % Si la derivada no es ninguna de las opciones anteriores, se muestra un mensaje de error
                    app.Label.Text = "Error: la derivada especificada no es válida";
                    return;
            end

            app.ValorAproximadoEditField.Value = sprintf('%.6f', resultado);


            derivada = diff(str2sym(funcion));
            valorReal = subs(derivada, x);
            app.ValorRealEditField.Value = sprintf('%.6f', valorReal);

            error = ((valorReal-resultado)/valorReal)*100;
            app.ErrorEditField.Value = sprintf('%.6f', error);


        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            app.Label.Text = "";                      %Obtención de datos
            derivada = app.DerivadaDropDown.Value;        %Método escogido
            x = app.ValorX.Value;        %Tamaño escogido
            funcion = app.FuncionEditField.Value;    %Valor de parada
            h = app.ValorSalto.Value;
            %se llama al metodo mandando todas las condiciones
            metodos(app, derivada, x, funcion, h);
        end

        % Callback function
        function CantidadPuntosDropDownValueChanged(app, event)

        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            MenuPrincipal
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DerivadaDropDownLabel = uilabel(app.UIFigure);
            app.DerivadaDropDownLabel.Text = 'Derivada';
            app.DerivadaDropDown = uidropdown(app.UIFigure);
            app.DerivadaDropDown.Items = {'Primera derivada', 'Segunda derivada', 'Tercera derivada', 'Cuarta derivada'};
            app.DerivadaDropDown.Tag = 'ddMetodo';
            app.DerivadaDropDown.Value = 'Primera derivada';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.ValoraevaliarLabel_2 = uilabel(app.UIFigure);
            app.ValoraevaliarLabel_2.Text = 'Valor de Salto (h)';
            app.ValorSalto = uieditfield(app.UIFigure, 'numeric');
            app.ValorSalto.Tag = 'txtValor';
            app.ValoraevaliarLabel_3 = uilabel(app.UIFigure);
            app.ValoraevaliarLabel_3.Text = 'Valor de X';
            app.ValorX = uieditfield(app.UIFigure, 'numeric');
            app.ValorX.Tag = 'txtValor';
            app.FuncionEditFieldLabel = uilabel(app.UIFigure);
            app.FuncionEditFieldLabel.Text = 'Funcion';
            app.FuncionEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Tag = 'lblMensaje';
            app.Label.Text = {''; ''};
            app.ValorAproximadoEditFieldLabel = uilabel(app.UIFigure);
            app.ValorAproximadoEditFieldLabel.Text = 'Valor Aproximado';
            app.ValorAproximadoEditField = uieditfield(app.UIFigure, 'text');
            app.ValorAproximadoEditField.Editable = 'off';
            app.ValorRealEditFieldLabel = uilabel(app.UIFigure);
            app.ValorRealEditFieldLabel.Text = 'Valor Real';
            app.ValorRealEditField = uieditfield(app.UIFigure, 'text');
            app.ValorRealEditField.Editable = 'off';
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorEditField.Editable = 'off';
            app.DerivadasLabel = uilabel(app.UIFigure);
            app.DerivadasLabel.Text = 'Derivadas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Derivadas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end