classdef GaussSeidel3x3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumeroMaxIteraciones          matlab.ui.control.NumericEditField
        NmeromximodeiteracionesLabel  matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        tabla                         matlab.ui.control.Table
        ResultadodelosxiLabel         matlab.ui.control.Label
        x3Label_5                     matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        x2Label_5                     matlab.ui.control.Label
        resultadox3                   matlab.ui.control.NumericEditField
        x1Label_9                     matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        resultadox2                   matlab.ui.control.NumericEditField
        Label_7                       matlab.ui.control.Label
        resultadox1                   matlab.ui.control.NumericEditField
        CriterioParada                matlab.ui.control.NumericEditField
        CriteriodeparadaenEaEditFieldLabel  matlab.ui.control.Label
        ValorinicialdelosxiLabel      matlab.ui.control.Label
        valorInicialx1                matlab.ui.control.NumericEditField
        valorInicialx2                matlab.ui.control.NumericEditField
        valorInicialx3                matlab.ui.control.NumericEditField
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        x1Label_8                     matlab.ui.control.Label
        x2Label_4                     matlab.ui.control.Label
        x3Label_4                     matlab.ui.control.Label
        SistemadeecuacionespreviamenteorganizadoLabel  matlab.ui.control.Label
        text5                         matlab.ui.control.Label
        a34                           matlab.ui.control.NumericEditField
        a24                           matlab.ui.control.NumericEditField
        a14                           matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        x3Label_3                     matlab.ui.control.Label
        a33                           matlab.ui.control.NumericEditField
        x2Label_3                     matlab.ui.control.Label
        a32                           matlab.ui.control.NumericEditField
        x1Label_7                     matlab.ui.control.Label
        a31                           matlab.ui.control.NumericEditField
        a21                           matlab.ui.control.NumericEditField
        x1Label_6                     matlab.ui.control.Label
        a22                           matlab.ui.control.NumericEditField
        x2Label_2                     matlab.ui.control.Label
        a23                           matlab.ui.control.NumericEditField
        x3Label_2                     matlab.ui.control.Label
        x3Label                       matlab.ui.control.Label
        a13                           matlab.ui.control.NumericEditField
        x2Label                       matlab.ui.control.Label
        a12                           matlab.ui.control.NumericEditField
        x1Label                       matlab.ui.control.Label
        a11                           matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        %-------------------------------------------------------
        % Runs the Gauss-Seidel method for A*x = b.
        %
        % ON ENTRY :
        %   A        n-by-n matrix
        %   b        n-dimensional vector
        %   epsilon  accuracy requirement
        %   maxit    maximal number of iterations
        %   x        start vector for the iteration
        %
        % ON RETURN :
        %   x        approximate solution to A*x = b.
        %-------------------------------------------------------
        function x = GS(app, A, b, epsilon, maxit, x)
            N=3;
            for n_iter=1:maxit
                xold = x;
                for i = 1:N

                    sum = b(i);
                    for j = 1:N

                        resultados(n_iter,1) = n_iter;
                        resultados(n_iter,2) = x(1);
                        resultados(n_iter,3) = x(2);
                        resultados(n_iter,4) = x(3);

                        C = num2cell(resultados);

                        app.tabla.Data = C;

                        if (j ~= i)
                            sum = sum - A(i,j) * x(j);
                        end
                    end
                    x(i) = sum/A(i,i);
                end


                error = abs((x(i)-xold(j))/x(i))*100
                resultados(n_iter,5) = error;
                C = num2cell(resultados);

                app.tabla.Data = C;

                if(error<epsilon)
                    break;
                end
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            A = [app.a11.Value app.a12.Value app.a13.Value; app.a21.Value app.a22.Value app.a23.Value; app.a31.Value app.a32.Value app.a33.Value];
            b = [app.a14.Value app.a24.Value app.a34.Value]';
            epsilon = app.CriterioParada.Value;
            maxit = (app.NumeroMaxIteraciones.Value);
            x = [app.valorInicialx1.Value app.valorInicialx2.Value app.valorInicialx3.Value];
            x1 = GS(app, A,b,epsilon,maxit,x);

            g=sprintf('%d ', x1);
            fprintf('Answer: %s\n', g)

            % Valores finales

            resX1 = x1(1);
            resX2 = x1(2);
            resX3 = x1(3);

            app.resultadox1.Value = resX1;
            app.resultadox2.Value = resX2;
            app.resultadox3.Value = resX3;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a11 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label = uilabel(app.UIFigure);
            app.x1Label.Text = 'x1';
            app.a12 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = 'x2';
            app.a13 = uieditfield(app.UIFigure, 'numeric');
            app.x3Label = uilabel(app.UIFigure);
            app.x3Label.Text = 'x3';
            app.x3Label_2 = uilabel(app.UIFigure);
            app.x3Label_2.Text = 'x3';
            app.a23 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label_2 = uilabel(app.UIFigure);
            app.x2Label_2.Text = 'x2';
            app.a22 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label_6 = uilabel(app.UIFigure);
            app.x1Label_6.Text = 'x1';
            app.a21 = uieditfield(app.UIFigure, 'numeric');
            app.a31 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label_7 = uilabel(app.UIFigure);
            app.x1Label_7.Text = 'x1';
            app.a32 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label_3 = uilabel(app.UIFigure);
            app.x2Label_3.Text = 'x2';
            app.a33 = uieditfield(app.UIFigure, 'numeric');
            app.x3Label_3 = uilabel(app.UIFigure);
            app.x3Label_3.Text = 'x3';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '=';
            app.a14 = uieditfield(app.UIFigure, 'numeric');
            app.a24 = uieditfield(app.UIFigure, 'numeric');
            app.a34 = uieditfield(app.UIFigure, 'numeric');
            app.text5 = uilabel(app.UIFigure);
            app.text5.Tag = 'text5';
            app.text5.Text = 'SOLUCIÓN DE SISTEMAS DE ECUACIONES 3X3 POR MEDIO DE GAUSS-SEIDEL';
            app.SistemadeecuacionespreviamenteorganizadoLabel = uilabel(app.UIFigure);
            app.SistemadeecuacionespreviamenteorganizadoLabel.Text = 'Sistema de ecuaciones previamente organizado';
            app.x3Label_4 = uilabel(app.UIFigure);
            app.x3Label_4.Text = 'x3';
            app.x2Label_4 = uilabel(app.UIFigure);
            app.x2Label_4.Text = 'x2';
            app.x1Label_8 = uilabel(app.UIFigure);
            app.x1Label_8.Text = 'x1';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '=';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '=';
            app.valorInicialx3 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx2 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx1 = uieditfield(app.UIFigure, 'numeric');
            app.ValorinicialdelosxiLabel = uilabel(app.UIFigure);
            app.ValorinicialdelosxiLabel.Text = 'Valor inicial de los xi';
            app.CriteriodeparadaenEaEditFieldLabel = uilabel(app.UIFigure);
            app.CriteriodeparadaenEaEditFieldLabel.Text = 'Criterio de parada en %Ea';
            app.CriterioParada = uieditfield(app.UIFigure, 'numeric');
            app.resultadox1 = uieditfield(app.UIFigure, 'numeric');
            app.resultadox1.Editable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '=';
            app.resultadox2 = uieditfield(app.UIFigure, 'numeric');
            app.resultadox2.Editable = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '=';
            app.x1Label_9 = uilabel(app.UIFigure);
            app.x1Label_9.Text = 'x1';
            app.resultadox3 = uieditfield(app.UIFigure, 'numeric');
            app.resultadox3.Editable = 'off';
            app.x2Label_5 = uilabel(app.UIFigure);
            app.x2Label_5.Text = 'x2';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '=';
            app.x3Label_5 = uilabel(app.UIFigure);
            app.x3Label_5.Text = 'x3';
            app.ResultadodelosxiLabel = uilabel(app.UIFigure);
            app.ResultadodelosxiLabel.Text = 'Resultado de los xi';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Iteración'; 'x1'; 'x2'; 'x3'; 'Error'};
            app.tabla.RowName = {};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.NmeromximodeiteracionesLabel = uilabel(app.UIFigure);
            app.NmeromximodeiteracionesLabel.Text = 'Número máximo de iteraciones';
            app.NumeroMaxIteraciones = uieditfield(app.UIFigure, 'numeric');
            app.NumeroMaxIteraciones.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaussSeidel3x3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end