classdef InterpolacionLagrange < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CantidadPuntosDropDown  matlab.ui.control.DropDown
        CantidaddepuntosLabel   matlab.ui.control.Label
        ValorEvaluarEditField   matlab.ui.control.NumericEditField
        ValoraevaliarLabel      matlab.ui.control.Label
        fX5EditField            matlab.ui.control.NumericEditField
        fX4EditField            matlab.ui.control.NumericEditField
        fX3EditField            matlab.ui.control.NumericEditField
        fX2EditField            matlab.ui.control.NumericEditField
        fX1EditField            matlab.ui.control.NumericEditField
        fX0EditField            matlab.ui.control.NumericEditField
        X5EditField             matlab.ui.control.NumericEditField
        X4EditField             matlab.ui.control.NumericEditField
        X3EditField             matlab.ui.control.NumericEditField
        X2EditField             matlab.ui.control.NumericEditField
        X1EditField             matlab.ui.control.NumericEditField
        X0EditField             matlab.ui.control.NumericEditField
        lblValPol_3             matlab.ui.control.Label
        lblValPol_2             matlab.ui.control.Label
        lblValPol               matlab.ui.control.Label
        lblPol                  matlab.ui.control.Label
        CalcularButton          matlab.ui.control.Button
        lblValAprox             matlab.ui.control.Label
        ValoraproximadoLabel    matlab.ui.control.Label
        CondicionInicialPanel   matlab.ui.container.Panel
        FXiLabel                matlab.ui.control.Label
        XiLabel                 matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function m = metodos(app, valorEvaluar, x, Fx)

            Y = 0;
            
            for i = 1: length(x)
                L = 1;
                for j = 1: length(x)
                    if i~=j
                        L = L * ((valorEvaluar-x(j)) / (x(i) - x(j)))
                    end
                end
                Y = Y + Fx(i) * L
            end

            yStr = sprintf('%.6f',Y);                 %Se le da formato de 6 decimales al valor aproximado
            app.lblValAprox.Text = yStr;

            polinomio = ""; % Inicializa el polinomio como una cadena vacía
            polinomio2 = ""
            polinomio3 = ""
            mas = ""

            for i = 1: length(x)
                denominador = 1;
                iteracion = ""
                for j=1: length(x)
                    if i~=j
                        denominador = denominador * (x(i) - x(j))
                        if x(j) < 0
                            iteracion = strcat(iteracion, "(x + ", num2str(abs(x(j))), ")");
                        else
                            iteracion = strcat(iteracion, "(x - ", num2str(x(j)), ")");
                        end
                    end
                end
                if(length(x)>=4)
                    if i<=2
                        polinomio = strcat(polinomio, mas,iteracion, "(", num2str(Fx(i)), "/", num2str(denominador),")");
                    elseif i <= 4
                        polinomio2 = strcat(polinomio2, mas,iteracion, "(", num2str(Fx(i)), "/", num2str(denominador),")");
                    else
                        polinomio3 = strcat(polinomio3, mas,iteracion, "(", num2str(Fx(i)), "/", num2str(denominador),")");
                    end
                else
                        polinomio = strcat(polinomio, mas,iteracion, "(", num2str(Fx(i)), "/", num2str(denominador),")");
                end
                mas = " + "
            end
   
            app.lblValPol.Text = polinomio
            app.lblValPol_2.Text = polinomio2
            app.lblValPol_3.Text = polinomio3

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            cantPuntos = app.CantidadPuntosDropDown.Value;        %Cantidad de puntos escogido
            valorEvaluar = app.ValorEvaluarEditField.Value;    %Valor a evaluar

            %Valores de los puntos a evaluar
            x0 = app.X0EditField.Value;
            x1 = app.X1EditField.Value;
            x2 = app.X2EditField.Value;
            x3 = app.X3EditField.Value;
            x4 = app.X4EditField.Value;
            x5 = app.X5EditField.Value;
            fx0 = app.fX0EditField.Value;
            fx1 = app.fX1EditField.Value;
            fx2 = app.fX2EditField.Value;
            fx3 = app.fX3EditField.Value;
            fx4 = app.fX4EditField.Value;
            fx5 = app.fX5EditField.Value;
               
            switch cantPuntos
                case "2 puntos"
                    x = [x0, x1];
                    Fx = [fx0, fx1];
                case "3 puntos"
                    x = [x0, x1, x2];
                    Fx = [fx0, fx1, fx2];
                case "4 puntos"
                    x = [x0, x1, x2, x3];
                    Fx = [fx0, fx1, fx2, fx3];
                case "5 puntos"
                    x = [x0, x1, x2, x3, x4];
                    Fx = [fx0, fx1, fx2, fx3, fx4];
                case "6 puntos"
                    x = [x0, x1, x2, x3, x4, x5];
                    Fx = [fx0, fx1, fx2, fx3, fx4, fx5];
            end
            %se llama al metodo mandando todas las condiciones
            metodos(app, valorEvaluar, x, Fx)
        end

        % Value changed function: CantidadPuntosDropDown
        function CantidadPuntosDropDownValueChanged(app, event)
            value = app.CantidadPuntosDropDown.Value;

            switch value
                case "2 puntos"
                    app.X2EditField.Enable = "off";
                    app.fX2EditField.Enable = "off";
                    app.X3EditField.Enable = "off";
                    app.fX3EditField.Enable = "off";
                    app.X4EditField.Enable = "off";
                    app.fX4EditField.Enable = "off";
                    app.X5EditField.Enable = "off";
                    app.fX5EditField.Enable = "off";  
                case "3 puntos"
                    app.X2EditField.Enable = "on";
                    app.fX2EditField.Enable = "on";
                    app.X3EditField.Enable = "off";
                    app.fX3EditField.Enable = "off";
                    app.X4EditField.Enable = "off";
                    app.fX4EditField.Enable = "off";
                    app.X5EditField.Enable = "off";
                    app.fX5EditField.Enable = "off";
                case "4 puntos"
                    app.X2EditField.Enable = "on";
                    app.fX2EditField.Enable = "on";
                    app.X3EditField.Enable = "on";
                    app.fX3EditField.Enable = "on";
                    app.X4EditField.Enable = "off";
                    app.fX4EditField.Enable = "off";
                    app.X5EditField.Enable = "off";
                    app.fX5EditField.Enable = "off";
                case "5 puntos"
                   app.X2EditField.Enable = "on";
                   app.fX2EditField.Enable = "on";
                   app.X3EditField.Enable = "on";
                   app.fX3EditField.Enable = "on";
                   app.X4EditField.Enable = "on";
                   app.fX4EditField.Enable = "on";
                   app.X5EditField.Enable = "off";
                   app.fX5EditField.Enable = "off"; 
                case "6 puntos"
                   app.X2EditField.Enable = "on";
                   app.fX2EditField.Enable = "on";
                   app.X3EditField.Enable = "on";
                   app.fX3EditField.Enable = "on";
                   app.X4EditField.Enable = "on";
                   app.fX4EditField.Enable = "on";
                   app.X5EditField.Enable = "on";
                   app.fX5EditField.Enable = "on"; 
            end
        end

        % Callback function: not associated with a component
        function VolverButtonPushed(app, event)
            Interpolaciones
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CondicionInicialPanel = uipanel(app.UIFigure);
            app.CondicionInicialPanel.Title = 'Condicion Inicial';
            app.XiLabel = uilabel(app.CondicionInicialPanel);
            app.XiLabel.Text = 'Xi';
            app.FXiLabel = uilabel(app.CondicionInicialPanel);
            app.FXiLabel.Text = 'F(Xi)';
            app.ValoraproximadoLabel = uilabel(app.UIFigure);
            app.ValoraproximadoLabel.Text = 'Valor aproximado: ';
            app.lblValAprox = uilabel(app.UIFigure);
            app.lblValAprox.Text = '';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.lblPol = uilabel(app.UIFigure);
            app.lblPol.Text = 'Polinomio: ';
            app.lblValPol = uilabel(app.UIFigure);
            app.lblValPol.Text = '';
            app.lblValPol_2 = uilabel(app.UIFigure);
            app.lblValPol_2.Text = '';
            app.lblValPol_3 = uilabel(app.UIFigure);
            app.lblValPol_3.Text = '';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X4EditField = uieditfield(app.UIFigure, 'numeric');
            app.X5EditField = uieditfield(app.UIFigure, 'numeric');
            app.fX0EditField = uieditfield(app.UIFigure, 'numeric');
            app.fX1EditField = uieditfield(app.UIFigure, 'numeric');
            app.fX2EditField = uieditfield(app.UIFigure, 'numeric');
            app.fX3EditField = uieditfield(app.UIFigure, 'numeric');
            app.fX4EditField = uieditfield(app.UIFigure, 'numeric');
            app.fX5EditField = uieditfield(app.UIFigure, 'numeric');
            app.ValoraevaliarLabel = uilabel(app.UIFigure);
            app.ValoraevaliarLabel.Text = 'Valor a evaluar';
            app.ValorEvaluarEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValorEvaluarEditField.Tag = 'txtValor';
            app.CantidaddepuntosLabel = uilabel(app.UIFigure);
            app.CantidaddepuntosLabel.Text = 'Cantidad  de puntos';
            app.CantidadPuntosDropDown = uidropdown(app.UIFigure);
            app.CantidadPuntosDropDown.Items = {'6 puntos', '5 puntos', '4 puntos', '3 puntos', '2 puntos'};
            app.CantidadPuntosDropDown.ValueChangedFcn = createCallbackFcn(app, @CantidadPuntosDropDownValueChanged, true);
            app.CantidadPuntosDropDown.Tag = 'ddMetodo';
            app.CantidadPuntosDropDown.Value = '6 puntos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterpolacionLagrange

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end