classdef InterpolacionNewton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1    matlab.ui.Figure
        uipanel4   matlab.ui.container.Panel
        resultado  matlab.ui.control.EditField
        calcular   matlab.ui.control.Button
        uipanel2   matlab.ui.container.Panel
        solucion   matlab.ui.control.NumericEditField
        text4      matlab.ui.control.Label
        valor      matlab.ui.control.EditField
        text3      matlab.ui.control.Label
        uipanel1   matlab.ui.container.Panel
        tabla      matlab.ui.control.Table
        dimension  matlab.ui.control.DropDown
        text2      matlab.ui.control.Label
        text1      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function untitled_OpeningFcn(app, varargin)
            % --- Executes just before untitled is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to untitled (see VARARGIN)
            
            % Choose default command line output for untitled
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function
        function Acercade_Callback(app, event)
          
        end

        % Callback function
        function Ayuda_Callback(app, event)

            
        end

        % Callback function
        function Salir_Callback(app, event)
           
        end

        % Button pushed function: calcular
        function calcular_Callback(app, event)
            % --- Executes on button press in calcular.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to calcular (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            g=get(handles.dimension,'Value');
            t=(get(handles.tabla,'data'));
            t=str2double(t);
            %w=isnan(t(1:g,1:g));
                xt=t(:,1);
                fx=t(:,2);
                x=xt;
                d=zeros(length(fx));
                d(:,1)=fx;
                for k=2:length(x)
                    for j=1:length(x)+1-k
                        d(j,k)=(d(j+1,k-1)-d(j,k-1))/(x(j+k-1)-x(j));
                    end
                end
                % Formacion del solucion
                for w=1:length(x)
                    ds=num2str(abs(d(1,w)));
                    if w>1
                        if x(w-1)<0
                            sg1='+';
                        else
                            sg1='-';
                        end
                    end
                    if d(1,w)<0
                        sg2='-';
                    else
                        sg2='+';
                    end
                    if w==1
                        acum=num2str(d(1,1));
                    elseif w==2
                        polact=['(x' sg1 num2str(abs(x(w-1))) ')' ];
                        actual=[ds '*' polact];
                        acum=[acum sg2 actual];
                    else
                        polact=[polact '*' '(x' sg1 num2str(abs(x(w-1))) ')' ];
                        actual=[ds '*' polact];
                        acum=[acum sg2 actual];
                    end
                end
            
                app.resultado.Value = acum;


            vx=app.valor.Value;
            vx=str2double(vx);
            
            p=app.resultado.Value;
            p=inline(p);
            s=(feval(p,vx));
        
            app.solucion.Value = s;


        end

        % Value changed function: dimension
        function dimension_Callback(app, event)
            % --- Executes on selection change in dimension.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dimension (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns dimension contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from dimension
            
            g=get(handles.dimension,'Value');
            
            switch g
            
                case 1
                    set(handles.tabla,'Visible','off');
                    
                case 2
                    set(handles.tabla,'Visible','on');
                    num_elem=cell(2,2);
                    num_elem(:,:)={''};
                    set(handles.tabla,'data',num_elem);
                    set(handles.tabla,'ColumnEditable',true(1,1));
                case 3
                    set(handles.tabla,'Visible','on');
                    num_elem=cell(3,2);
                    num_elem(:,:)={''};
                    set(handles.tabla,'data',num_elem);
                    set(handles.tabla,'ColumnEditable',true(1,1));
                case 4
                    set(handles.tabla,'Visible','on');
                    num_elem=cell(4,2);
                    num_elem(:,:)={''};
                    set(handles.tabla,'data',num_elem);
                    set(handles.tabla,'ColumnEditable',true(1,1));
                 case 5
                    set(handles.tabla,'Visible','on');
                    num_elem=cell(5,2);
                    num_elem(:,:)={''};
                    set(handles.tabla,'data',num_elem);
                    set(handles.tabla,'ColumnEditable',true(1,1));
                 case 6
                    set(handles.tabla,'Visible','on');
                    num_elem=cell(6,2);
                    num_elem(:,:)={''};
                    set(handles.tabla,'data',num_elem);
                    set(handles.tabla,'ColumnEditable',true(1,1));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'untitled';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Interpolación de Newton';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Introducir Valores';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Numero de puntos:';
            app.dimension = uidropdown(app.uipanel1);
            app.dimension.Items = {'', '2', '3', '4', '5', '6'};
            app.dimension.ValueChangedFcn = createCallbackFcn(app, @dimension_Callback, true);
            app.dimension.Tag = 'dimension';
            app.dimension.Value = '';
            app.tabla = uitable(app.uipanel1);
            app.tabla.Visible = 'off';
            app.tabla.Tag = 'tabla';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Evaluar';
            app.uipanel2.Tag = 'uipanel2';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'X';
            app.valor = uieditfield(app.uipanel2, 'text');
            app.valor.Tag = 'valor';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Solucion';
            app.solucion = uieditfield(app.uipanel2, 'numeric');
            app.solucion.ValueDisplayFormat = '%.6f';
            app.solucion.Editable = 'off';
            app.calcular = uibutton(app.figure1, 'push');
            app.calcular.ButtonPushedFcn = createCallbackFcn(app, @calcular_Callback, true);
            app.calcular.Tag = 'calcular';
            app.calcular.Text = 'Calcular';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Polinomio';
            app.uipanel4.Tag = 'uipanel4';
            app.resultado = uieditfield(app.uipanel4, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterpolacionNewton(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)untitled_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end