classdef Jacobi5x5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label_16                      matlab.ui.control.Label
        x5Label_7                     matlab.ui.control.Label
        resultadox5                   matlab.ui.control.NumericEditField
        Label_15                      matlab.ui.control.Label
        x5Label_6                     matlab.ui.control.Label
        valorInicialx5                matlab.ui.control.NumericEditField
        a56                           matlab.ui.control.NumericEditField
        Label_14                      matlab.ui.control.Label
        x5Label_5                     matlab.ui.control.Label
        a55                           matlab.ui.control.NumericEditField
        x4Label_11                    matlab.ui.control.Label
        a54                           matlab.ui.control.NumericEditField
        x3Label_11                    matlab.ui.control.Label
        a53                           matlab.ui.control.NumericEditField
        x2Label_11                    matlab.ui.control.Label
        a52                           matlab.ui.control.NumericEditField
        a51                           matlab.ui.control.NumericEditField
        x1Label_12                    matlab.ui.control.Label
        a45                           matlab.ui.control.NumericEditField
        a35                           matlab.ui.control.NumericEditField
        a25                           matlab.ui.control.NumericEditField
        a15                           matlab.ui.control.NumericEditField
        x5Label_4                     matlab.ui.control.Label
        x5Label_3                     matlab.ui.control.Label
        x5Label_2                     matlab.ui.control.Label
        x5Label                       matlab.ui.control.Label
        x4Label_6                     matlab.ui.control.Label
        Label_13                      matlab.ui.control.Label
        resultadox4                   matlab.ui.control.NumericEditField
        x4Label                       matlab.ui.control.Label
        Label_12                      matlab.ui.control.Label
        valorInicialx4                matlab.ui.control.NumericEditField
        a46                           matlab.ui.control.NumericEditField
        Label_11                      matlab.ui.control.Label
        x4Label_5                     matlab.ui.control.Label
        a44                           matlab.ui.control.NumericEditField
        x3Label_10                    matlab.ui.control.Label
        a43                           matlab.ui.control.NumericEditField
        x2Label_10                    matlab.ui.control.Label
        a42                           matlab.ui.control.NumericEditField
        x1Label_11                    matlab.ui.control.Label
        a41                           matlab.ui.control.NumericEditField
        x4Label_4                     matlab.ui.control.Label
        a34                           matlab.ui.control.NumericEditField
        a24                           matlab.ui.control.NumericEditField
        x4Label_3                     matlab.ui.control.Label
        x4Label_2                     matlab.ui.control.Label
        a14                           matlab.ui.control.NumericEditField
        NumeroMaxIteraciones          matlab.ui.control.NumericEditField
        NmeromximodeiteracionesLabel  matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        tabla                         matlab.ui.control.Table
        ResultadodelosxiLabel         matlab.ui.control.Label
        x3Label_5                     matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        x2Label_5                     matlab.ui.control.Label
        resultadox3                   matlab.ui.control.NumericEditField
        x1Label_9                     matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        resultadox2                   matlab.ui.control.NumericEditField
        Label_7                       matlab.ui.control.Label
        resultadox1                   matlab.ui.control.NumericEditField
        CriterioParada                matlab.ui.control.NumericEditField
        CriteriodeparadaenEaEditFieldLabel  matlab.ui.control.Label
        ValorinicialdelosxiLabel      matlab.ui.control.Label
        valorInicialx1                matlab.ui.control.NumericEditField
        valorInicialx2                matlab.ui.control.NumericEditField
        valorInicialx3                matlab.ui.control.NumericEditField
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        x1Label_8                     matlab.ui.control.Label
        x2Label_4                     matlab.ui.control.Label
        x3Label_4                     matlab.ui.control.Label
        SistemadeecuacionespreviamenteorganizadoLabel  matlab.ui.control.Label
        text5                         matlab.ui.control.Label
        a36                           matlab.ui.control.NumericEditField
        a26                           matlab.ui.control.NumericEditField
        a16                           matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        x3Label_3                     matlab.ui.control.Label
        a33                           matlab.ui.control.NumericEditField
        x2Label_3                     matlab.ui.control.Label
        a32                           matlab.ui.control.NumericEditField
        x1Label_7                     matlab.ui.control.Label
        a31                           matlab.ui.control.NumericEditField
        a21                           matlab.ui.control.NumericEditField
        x1Label_6                     matlab.ui.control.Label
        a22                           matlab.ui.control.NumericEditField
        x2Label_2                     matlab.ui.control.Label
        a23                           matlab.ui.control.NumericEditField
        x3Label_2                     matlab.ui.control.Label
        x3Label                       matlab.ui.control.Label
        a13                           matlab.ui.control.NumericEditField
        x2Label                       matlab.ui.control.Label
        a12                           matlab.ui.control.NumericEditField
        x1Label                       matlab.ui.control.Label
        a11                           matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        %-------------------------------------------------------
        % Runs the Jacobi method for A*x = b.
        %
        % ON ENTRY :
        %   A        n-by-n matrix
        %   b        n-dimensional vector
        %   epsilon  accuracy requirement
        %   maxit    maximal number of iterations
        %   x        start vector for the iteration
        %
        % ON RETURN :
        %   x        approximate solution to A*x = b.
        %-------------------------------------------------------
        function x = Jacobi(app, A, b, epsilon, maxit, x)

        % Hace el error en porcentaje, ya que anteriormente, si uno ponía,
        % por ejemplo 1, era el 100%; ya con esto, al poner 1, es el 1%
        epsilon = epsilon/100;
        
        % check if the entered matrix is a square matrix
        [na, ma] = size(A);
        if na ~= ma
            disp('ERROR: Matrix A must be a square matrix')
            return
        end
        
        % check if b is a column matrix
        [nb, mb] = size (b);
        if nb ~= na || mb~=1
           disp( 'ERROR: Matrix b must be a column matrix')
           return
        end
        
        % Crea una columna de matriz de ceros 
        dx = zeros(na,1);
        resultados = zeros(1,7);

        % Desde uno hasta el máximo de iteraciones comienza a hacer los
        % cálculos
        for k=1:maxit
            % K es la cantidad de intervalos hechos
            sum = 0;
            % inicia la k-ésima iteración
            for i=1:na
                % Coge cada uno de los valores independientes
                dx(i) = b(i);
                for j=1:na

                resultados(k,1) = k;
                resultados(k,2) = x(1);
                resultados(k,3) = x(2);
                resultados(k,4) = x(3);
                resultados(k,5) = x(4);
                resultados(k,6) = x(5);

                C = num2cell(resultados);

                app.tabla.Data = C;


                    % El siguiente resultado de xi es igual a la variable
                    % independiente, menos cada uno de los vectores de la
                    % fila correspondiente, en este caso A(i, j) es el
                    % coeficiente y el x(j) es el valor de xi en la
                    % iteración anterior y el dx(i) es el valor
                    % independiente
                    dx(i) = dx(i) - A(i,j)*x(j); 
                    % Lo hace con todas las filas hasta que na llegue a su
                    % máximo que es el tamaño de la matriz
                end 
                % Divide el valor dado para xi sobre el coeficiente de ese
                % xi
                dx(i) = dx(i)/A(i,i);  

                x(i) = x(i) + dx(i);


                % Calcula todos errores en uno solo por medio de sumas iterativas
                if (dx(i) >= 0)
                    sum = sum + dx(i);
                else 
                    sum = sum - dx(i);
                end

                resultados(k,7) = sum;
                C = num2cell(resultados);

                app.tabla.Data = C;

            end

            % finaliza la la k-ésima iteración

             
            if(sum <= epsilon)
                % rompe si los errores dados son menores a lo dado
                break
            end
        end        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            A = [app.a11.Value app.a12.Value app.a13.Value app.a14.Value app.a15.Value; app.a21.Value app.a22.Value app.a23.Value app.a24.Value app.a25.Value; app.a31.Value app.a32.Value app.a33.Value app.a34.Value app.a35.Value; app.a41.Value app.a42.Value app.a43.Value app.a44.Value app.a45.Value; app.a51.Value app.a52.Value app.a53.Value app.a54.Value app.a55.Value];
            b = [app.a16.Value app.a26.Value app.a36.Value app.a46.Value app.a56.Value]';
            epsilon = app.CriterioParada.Value;
            maxit = (app.NumeroMaxIteraciones.Value);
            x = [app.valorInicialx1.Value app.valorInicialx2.Value app.valorInicialx3.Value app.valorInicialx4.Value app.valorInicialx5.Value];
            x1 = Jacobi(app, A,b,epsilon,maxit,x);

            g=sprintf('%d ', x1);
            fprintf('Answer: %s\n', g)

            % Valores finales

            resX1 = x1(1);
            resX2 = x1(2);
            resX3 = x1(3);
            resX4 = x1(4);
            resX5 = x1(5);

            app.resultadox1.Value = resX1;
            app.resultadox2.Value = resX2;
            app.resultadox3.Value = resX3;
            app.resultadox4.Value = resX4;
            app.resultadox5.Value = resX5;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a11 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label = uilabel(app.UIFigure);
            app.x1Label.Text = 'x1';
            app.a12 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = 'x2';
            app.a13 = uieditfield(app.UIFigure, 'numeric');
            app.x3Label = uilabel(app.UIFigure);
            app.x3Label.Text = 'x3';
            app.x3Label_2 = uilabel(app.UIFigure);
            app.x3Label_2.Text = 'x3';
            app.a23 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label_2 = uilabel(app.UIFigure);
            app.x2Label_2.Text = 'x2';
            app.a22 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label_6 = uilabel(app.UIFigure);
            app.x1Label_6.Text = 'x1';
            app.a21 = uieditfield(app.UIFigure, 'numeric');
            app.a31 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label_7 = uilabel(app.UIFigure);
            app.x1Label_7.Text = 'x1';
            app.a32 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label_3 = uilabel(app.UIFigure);
            app.x2Label_3.Text = 'x2';
            app.a33 = uieditfield(app.UIFigure, 'numeric');
            app.x3Label_3 = uilabel(app.UIFigure);
            app.x3Label_3.Text = 'x3';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '=';
            app.a16 = uieditfield(app.UIFigure, 'numeric');
            app.a26 = uieditfield(app.UIFigure, 'numeric');
            app.a36 = uieditfield(app.UIFigure, 'numeric');
            app.text5 = uilabel(app.UIFigure);
            app.text5.Tag = 'text5';
            app.text5.Text = 'SOLUCIÓN DE SISTEMAS DE ECUACIONES 5X5 POR MEDIO DE JACOBI';
            app.SistemadeecuacionespreviamenteorganizadoLabel = uilabel(app.UIFigure);
            app.SistemadeecuacionespreviamenteorganizadoLabel.Text = 'Sistema de ecuaciones previamente organizado';
            app.x3Label_4 = uilabel(app.UIFigure);
            app.x3Label_4.Text = 'x3';
            app.x2Label_4 = uilabel(app.UIFigure);
            app.x2Label_4.Text = 'x2';
            app.x1Label_8 = uilabel(app.UIFigure);
            app.x1Label_8.Text = 'x1';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '=';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '=';
            app.valorInicialx3 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx2 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx1 = uieditfield(app.UIFigure, 'numeric');
            app.ValorinicialdelosxiLabel = uilabel(app.UIFigure);
            app.ValorinicialdelosxiLabel.Text = 'Valor inicial de los xi';
            app.CriteriodeparadaenEaEditFieldLabel = uilabel(app.UIFigure);
            app.CriteriodeparadaenEaEditFieldLabel.Text = 'Criterio de parada en %Ea';
            app.CriterioParada = uieditfield(app.UIFigure, 'numeric');
            app.resultadox1 = uieditfield(app.UIFigure, 'numeric');
            app.resultadox1.Editable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '=';
            app.resultadox2 = uieditfield(app.UIFigure, 'numeric');
            app.resultadox2.Editable = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '=';
            app.x1Label_9 = uilabel(app.UIFigure);
            app.x1Label_9.Text = 'x1';
            app.resultadox3 = uieditfield(app.UIFigure, 'numeric');
            app.resultadox3.Editable = 'off';
            app.x2Label_5 = uilabel(app.UIFigure);
            app.x2Label_5.Text = 'x2';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '=';
            app.x3Label_5 = uilabel(app.UIFigure);
            app.x3Label_5.Text = 'x3';
            app.ResultadodelosxiLabel = uilabel(app.UIFigure);
            app.ResultadodelosxiLabel.Text = 'Resultado de los xi';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Iteración'; 'x1'; 'x2'; 'x3'; 'x4'; 'x5'; 'Error'};
            app.tabla.RowName = {};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.NmeromximodeiteracionesLabel = uilabel(app.UIFigure);
            app.NmeromximodeiteracionesLabel.Text = 'Número máximo de iteraciones';
            app.NumeroMaxIteraciones = uieditfield(app.UIFigure, 'numeric');
            app.NumeroMaxIteraciones.Value = 100;
            app.a14 = uieditfield(app.UIFigure, 'numeric');
            app.x4Label_2 = uilabel(app.UIFigure);
            app.x4Label_2.Text = 'x4';
            app.x4Label_3 = uilabel(app.UIFigure);
            app.x4Label_3.Text = 'x4';
            app.a24 = uieditfield(app.UIFigure, 'numeric');
            app.a34 = uieditfield(app.UIFigure, 'numeric');
            app.x4Label_4 = uilabel(app.UIFigure);
            app.x4Label_4.Text = 'x4';
            app.a41 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label_11 = uilabel(app.UIFigure);
            app.x1Label_11.Text = 'x1';
            app.a42 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label_10 = uilabel(app.UIFigure);
            app.x2Label_10.Text = 'x2';
            app.a43 = uieditfield(app.UIFigure, 'numeric');
            app.x3Label_10 = uilabel(app.UIFigure);
            app.x3Label_10.Text = 'x3';
            app.a44 = uieditfield(app.UIFigure, 'numeric');
            app.x4Label_5 = uilabel(app.UIFigure);
            app.x4Label_5.Text = 'x4';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '=';
            app.a46 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx4 = uieditfield(app.UIFigure, 'numeric');
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '=';
            app.x4Label = uilabel(app.UIFigure);
            app.x4Label.Text = 'x4';
            app.resultadox4 = uieditfield(app.UIFigure, 'numeric');
            app.resultadox4.Editable = 'off';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '=';
            app.x4Label_6 = uilabel(app.UIFigure);
            app.x4Label_6.Text = 'x4';
            app.x5Label = uilabel(app.UIFigure);
            app.x5Label.Text = 'x5';
            app.x5Label_2 = uilabel(app.UIFigure);
            app.x5Label_2.Text = 'x5';
            app.x5Label_3 = uilabel(app.UIFigure);
            app.x5Label_3.Text = 'x5';
            app.x5Label_4 = uilabel(app.UIFigure);
            app.x5Label_4.Text = 'x5';
            app.a15 = uieditfield(app.UIFigure, 'numeric');
            app.a25 = uieditfield(app.UIFigure, 'numeric');
            app.a35 = uieditfield(app.UIFigure, 'numeric');
            app.a45 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label_12 = uilabel(app.UIFigure);
            app.x1Label_12.Text = 'x1';
            app.a51 = uieditfield(app.UIFigure, 'numeric');
            app.a52 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label_11 = uilabel(app.UIFigure);
            app.x2Label_11.Text = 'x2';
            app.a53 = uieditfield(app.UIFigure, 'numeric');
            app.x3Label_11 = uilabel(app.UIFigure);
            app.x3Label_11.Text = 'x3';
            app.a54 = uieditfield(app.UIFigure, 'numeric');
            app.x4Label_11 = uilabel(app.UIFigure);
            app.x4Label_11.Text = 'x4';
            app.a55 = uieditfield(app.UIFigure, 'numeric');
            app.x5Label_5 = uilabel(app.UIFigure);
            app.x5Label_5.Text = 'x5';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '=';
            app.a56 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx5 = uieditfield(app.UIFigure, 'numeric');
            app.x5Label_6 = uilabel(app.UIFigure);
            app.x5Label_6.Text = 'x5';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '=';
            app.resultadox5 = uieditfield(app.UIFigure, 'numeric');
            app.resultadox5.Editable = 'off';
            app.x5Label_7 = uilabel(app.UIFigure);
            app.x5Label_7.Text = 'x5';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '=';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Jacobi5x5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end