classdef MetodoBiseccion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        text6        matlab.ui.control.Label
        uipanel1     matlab.ui.container.Panel
        calcular     matlab.ui.control.Button
        limpiar      matlab.ui.control.Button
        tolerancia   matlab.ui.control.EditField
        limSuperior  matlab.ui.control.EditField
        limInferior  matlab.ui.control.EditField
        funcion      matlab.ui.control.EditField
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        text1        matlab.ui.control.Label
        tabla        matlab.ui.control.Table
        respuesta    matlab.ui.control.EditField
        text5        matlab.ui.control.Label
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.tabla.BackgroundColor = [0.8314 0.8157 0.7843];
            app.tabla.ColumnFormat = {[] [] [] [] [] [] [] []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function frmBiseccion_OpeningFcn(app, varargin)
            % --- Executes just before frmBiseccion is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to frmBiseccion (see VARARGIN)
            
            % Choose default command line output for frmBiseccion
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: calcular
        function calcular_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            f=get(handles.funcion,'string'); %Obtenemos la funcion
            f=inline(f); %Reconocemos al string como una funcion
            xai=str2double(get(handles.limInferior,'string')); %Limite inferior
            xas=str2double(get(handles.limSuperior,'string')); %Limite superior
            tol=str2double(get(handles.tolerancia,'string')); %Tolerancia
            i=1;%Iterador
            ea(i)=100; %Error inicial
            
            if f(xai)*f(xas)<0; %Comprobando que la raiz se encuentra en este intervalo
            xi(1)=xai;          %se calcula xr
            xs(1)=xas;          
            xr(1)=(xi(1)+xs(1))/2;
            
            set(handles.tabla,'Data',{}) %Se setea a la tabla valores vacios
            
         
            set(handles.respuesta,'string','No hay raiz'); %Si el if falla se da por echo que no hay raiz es esos limites
            % ============= Mostrara datos iniciales en la tabla ==============================
                newRow1 ={1 xi(1) xr(1) xs(1) f(xi(1)) f(xr(1)) f(xs(1)) ea(1)};
                oldData1 = get(handles.tabla,'Data');
                newData1=[oldData1; newRow1];
                set(handles.tabla,'Data',newData1)
            while abs(ea(i))>=tol %Mientras esto sea falso se hara todo lo que este dentro del while
                
                 if f(xi(i))*f(xr(i))<0  %Si el producto de la funcion evaluada en xi y xr es negativo
                       xi(i+1)=xi(i);    %xs pasa a tener el valor de xr
                       xs(i+1)=xr(i);
                 end
                 if f(xi(i))*f(xr(i))>0 %Si el producto de la funcion evaluada en xi y xr es positivo
                      xi(i+1)=xr(i);    %xi pasa a tener el valor de xs
                      xs(i+1)=xs(i);
                 end
               xr(i+1)=(xi(i+1)+xs(i+1))/2; %Se calcula xr+1
               ea(i+1)=abs((xr(i+1)-xr(i))/(xr(i+1))*100); %Se calcula el error
           
                
                % ============= Mostrara datos en tabla ==============================
                newRow ={i+1 xi(i+1) xr(i+1) xs(i+1) f(xi(i+1)) f(xr(i+1)) f(xs(i+1)) ea(i+1)};
                oldData = get(handles.tabla,'Data');
                newData=[oldData; newRow];
                set(handles.tabla,'Data',newData)
               
                i=i+1; % Aumentamos el iterador
                
            end
            respuesta=sprintf('%0.6f',xr(i)); %Se especifica que la variable respuesta va almacenar un tipo double
            set(handles.respuesta,'string',respuesta); %Se setea
           
           
            else
            set(handles.respuesta,'string','No existe la raiz en el intervalo');
            
            end
        end

        % Button pushed function: limpiar
        function limpiar_Callback(app, event)
            % --- Executes on button press in pushbutton2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            set(handles.tabla,'Data',{})
            %limpiar textboxs
            set(handles.funcion,'string','');
            set(handles.limInferior,'string','');
            set(handles.limSuperior,'string','');
            set(handles.tolerancia,'string','');
            set(handles.respuesta,'string','');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'frmBiseccion';
            app.figure1.Tag = 'figure1';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'La Raiz es:';
            app.respuesta = uieditfield(app.figure1, 'text');
            app.respuesta.Tag = 'respuesta';
            app.tabla = uitable(app.figure1);
            app.tabla.ColumnName = {'Iteracion'; 'xi'; 'xr'; 'xs'; 'f(xi)'; 'f(xr)'; 'f(xs)'; 'Ea(%)'};
            app.tabla.ColumnEditable = [false false false false false false false false];
            app.tabla.Tag = 'tabla';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Funcion Matematica:';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Limite inferior:';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Limite superior:';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Tolerancia:';
            app.funcion = uieditfield(app.uipanel1, 'text');
            app.funcion.Tag = 'funcion';
            app.limInferior = uieditfield(app.uipanel1, 'text');
            app.limInferior.Tag = 'limInferior';
            app.limSuperior = uieditfield(app.uipanel1, 'text');
            app.limSuperior.Tag = 'limSuperior';
            app.tolerancia = uieditfield(app.uipanel1, 'text');
            app.tolerancia.Tag = 'tolerancia';
            app.limpiar = uibutton(app.uipanel1, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiar_Callback, true);
            app.limpiar.Tag = 'limpiar';
            app.limpiar.Text = 'Limpiar';
            app.calcular = uibutton(app.uipanel1, 'push');
            app.calcular.ButtonPushedFcn = createCallbackFcn(app, @calcular_Callback, true);
            app.calcular.Tag = 'calcular';
            app.calcular.Text = 'Calcular';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'METODO DE LA BISECCION';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoBiseccion(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)frmBiseccion_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end