classdef RK3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        valorFinalY      matlab.ui.control.NumericEditField
        yLabel           matlab.ui.control.Label
        uipanel1         matlab.ui.container.Panel
        y0               matlab.ui.control.NumericEditField
        x0               matlab.ui.control.NumericEditField
        cantIteraciones  matlab.ui.control.NumericEditField
        h                matlab.ui.control.NumericEditField
        btnCalcular      matlab.ui.control.Button
        text4_3          matlab.ui.control.Label
        text4_2          matlab.ui.control.Label
        btnLimpiar       matlab.ui.control.Button
        text4            matlab.ui.control.Label
        text3            matlab.ui.control.Label
        funcion          matlab.ui.control.EditField
        text1            matlab.ui.control.Label
        tabla            matlab.ui.control.Table
        text5            matlab.ui.control.Label
        grafico          matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function addRuntimeConfigurations(app)

            % Setea las propiedades de los componentes que requieren una
            % configuración en tiempo de ejecución
            app.tabla.ColumnFormat = {[] [] [] []};
            endfunction results = func(app)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnCalcular
        function btnCalcularButtonPushed(app, event)

            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            f = app.funcion.Value;
            fxy = str2func(['@(x,y)' f]);
            valorh = app.h.Value;
            numIter = app.cantIteraciones.Value;
            xi = app.x0.Value;
            yi = app.y0.Value;


            ValoresY = zeros(1, numIter);
            ValoresX = zeros(1, numIter);
            ResultadosIteracion = zeros(1, 8);

            for k=1:numIter
                ResultadosIteracion(k, 1) = k;
                ResultadosIteracion(k, 2) = xi;
                ResultadosIteracion(k, 3) = yi;
                k1 = fxy(xi, yi);
                k2 = fxy((xi+valorh/2),(yi+(k1*valorh)/2));
                k3 = fxy((xi+valorh),(yi+2*valorh*k2-valorh*k1));
                yanterior = yi
                ynuevo = yi+(valorh/6)*(k1+4*k2+k3);
                error = ((ynuevo-yanterior)/(ynuevo))*100;
                error = abs(error);
                yi = ynuevo;
                xnuevo = xi + valorh;
                xi = xnuevo;
                ValoresY(1, k) = yi;
                ValoresX(1, k) = xi;


                ResultadosIteracion(k, 4) = k1;
                ResultadosIteracion(k, 5) = k2;
                ResultadosIteracion(k, 6) = k3;
                ResultadosIteracion(k, 7) = yi;
                ResultadosIteracion(k, 8) = error;

                C = num2cell(ResultadosIteracion);

                app.tabla.Data = C;

            end
            app.valorFinalY.Value = yi;
            plot(app.grafico, ValoresX, ValoresY);


        end

        % Button pushed function: btnLimpiar
        function btnLimpiarButtonPushed(app, event)
            set(app.tabla,'Data',{})
            %limpiar textboxs
            app.funcion.Value = "";
            app.h.Value=0;
            app.valorFinalY.Value=0;
            app.x0.Value=0;
            app.y0.Value=0; 
            app.cantIteraciones.Value=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafico = uiaxes(app.UIFigure);
            title(app.grafico, 'Gráfica de Y')
            xlabel(app.grafico, 'X')
            ylabel(app.grafico, 'Y')
            zlabel(app.grafico, 'Z')
            app.text5 = uilabel(app.UIFigure);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Runge - Kutta 3';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Iteracion'; 'xi'; 'yi'; 'k1'; 'k2'; 'k3'; 'yi+1'; 'error %'};
            app.tabla.ColumnEditable = [false false false false false false false];
            app.tabla.Tag = 'tabla';
            app.uipanel1 = uipanel(app.UIFigure);
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'ED Despejada de la forma  (dy/dx) = f(x,y)';
            app.funcion = uieditfield(app.uipanel1, 'text');
            app.funcion.Tag = 'funcion';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'h:';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Valor Inicial x:';
            app.btnLimpiar = uibutton(app.uipanel1, 'push');
            app.btnLimpiar.ButtonPushedFcn = createCallbackFcn(app, @btnLimpiarButtonPushed, true);
            app.btnLimpiar.Tag = 'btnLimpiar';
            app.btnLimpiar.Text = 'Limpiar';
            app.text4_2 = uilabel(app.uipanel1);
            app.text4_2.Tag = 'text4';
            app.text4_2.Text = 'Valor Inicial y:';
            app.text4_3 = uilabel(app.uipanel1);
            app.text4_3.Tag = 'text4';
            app.text4_3.Text = 'Cantidad de iteraciones:';
            app.btnCalcular = uibutton(app.uipanel1, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcularButtonPushed, true);
            app.btnCalcular.Tag = 'btnCalcular';
            app.btnCalcular.Text = 'Calcular';
            app.h = uieditfield(app.uipanel1, 'numeric');
            app.cantIteraciones = uieditfield(app.uipanel1, 'numeric');
            app.cantIteraciones.Value = 10;
            app.x0 = uieditfield(app.uipanel1, 'numeric');
            app.y0 = uieditfield(app.uipanel1, 'numeric');
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y=';
            app.valorFinalY = uieditfield(app.UIFigure, 'numeric');
            app.valorFinalY.ValueDisplayFormat = '%.6f';
            app.valorFinalY.Tag = 'respuesta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RK3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end