classdef TrapecioMultiple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Error                       matlab.ui.control.NumericEditField
        ValoraproxLabel_2           matlab.ui.control.Label
        N                           matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        valorAprox                  matlab.ui.control.NumericEditField
        ValoraproxLabel             matlab.ui.control.Label
        b                           matlab.ui.control.NumericEditField
        IngresevalorFinalLabel      matlab.ui.control.Label
        funcion                     matlab.ui.control.EditField
        IngreselaintegralLabel      matlab.ui.control.Label
        a                           matlab.ui.control.NumericEditField
        IngreselimitesuperiorLabel  matlab.ui.control.Label
        CALCULAR                    matlab.ui.control.Button
        TITULO                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULAR
        function CALCULARButtonPushed(app, event)
                    funcion = app.funcion.Value;
                    a = app.a.Value;
                    b = app.b.Value;
                    N = app.N.Value;
                    
                    % Verifica que a, b y N sean números reales y enteros positivos
                    if ~isnumeric(a) || ~isreal(a) || ~isnumeric(b) || ~isreal(b) || ~isnumeric(N) || ~isreal(N) || N < 1 || rem(N,1) ~= 0
                        error('Los valores de a, b y N deben ser números reales y enteros positivos.');
                    end
                    
                    % Verifica que la función ingresada sea válida
                    try
                        f = str2func(strcat('@(x) ',funcion));
                    catch
                        error('La función ingresada no es válida.');
                    end
                    
                    h = (b-a)/N;
                    
                    s = 0;
                    m = (feval(f,a)+feval(f,b))*h/2;
                    for i = 1:N-1
                        s = feval(f,a+(i)*h)*h+s;
                    end
                    
                    R = m+s;
                    app.valorAprox.Value = R;
                    
                    valorReal = integral(f,a,b,"ArrayValued",true); % Calcula el valor real de la integral
                    E = abs((valorReal-R)/valorReal)*100;
                    app.Error.Value = E;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TITULO = uilabel(app.UIFigure);
            app.TITULO.Text = 'Trapecio Multiple';
            app.CALCULAR = uibutton(app.UIFigure, 'push');
            app.CALCULAR.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULAR.Text = 'Calcular';
            app.IngreselimitesuperiorLabel = uilabel(app.UIFigure);
            app.IngreselimitesuperiorLabel.Text = 'Ingrese limite inferior';
            app.a = uieditfield(app.UIFigure, 'numeric');
            app.IngreselaintegralLabel = uilabel(app.UIFigure);
            app.IngreselaintegralLabel.Text = 'Ingrese la integral';
            app.funcion = uieditfield(app.UIFigure, 'text');
            app.IngresevalorFinalLabel = uilabel(app.UIFigure);
            app.IngresevalorFinalLabel.Text = 'Ingrese limite superior';
            app.b = uieditfield(app.UIFigure, 'numeric');
            app.ValoraproxLabel = uilabel(app.UIFigure);
            app.ValoraproxLabel.Text = 'Valor aprox';
            app.valorAprox = uieditfield(app.UIFigure, 'numeric');
            app.valorAprox.ValueDisplayFormat = '%.6f';
            app.valorAprox.Editable = 'off';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.N = uieditfield(app.UIFigure, 'numeric');
            app.ValoraproxLabel_2 = uilabel(app.UIFigure);
            app.ValoraproxLabel_2.Text = 'Error';
            app.Error = uieditfield(app.UIFigure, 'numeric');
            app.Error.ValueDisplayFormat = '%.6f';
            app.Error.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrapecioMultiple

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end